classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        LabelEditField         matlab.ui.control.Label            % File Name:
        FieldFileName          matlab.ui.control.EditField        % Stocks/...
        ButtonOpen             matlab.ui.control.Button           % Open
        UIAxes                 matlab.ui.control.UIAxes           % Year
        LabelDropDown          matlab.ui.control.Label            % Year
        DropDownYear           matlab.ui.control.DropDown         % 2007, 2...
        ButtonShowStats        matlab.ui.control.Button           % Show Stats
        ButtonInvest           matlab.ui.control.Button           % Investm...
        LabelDropDown2         matlab.ui.control.Label            % Investm...
        DropDown510            matlab.ui.control.DropDown         % 5, 10
        PanelInvestmentStats   matlab.ui.container.Panel          % Investm...
        LabelNumericEditField  matlab.ui.control.Label            % Daily S...
        FieldDailySpend        matlab.ui.control.NumericEditField % [1 10000]
        LabelNumericEditField2 matlab.ui.control.Label            % Polynom...
        FieldDegree            matlab.ui.control.NumericEditField % [8 20]
        LabelNumericEditField3 matlab.ui.control.Label            % Ridge L...
        FieldLambda            matlab.ui.control.NumericEditField % [0 1]
        CheckMix               matlab.ui.control.CheckBox         % Mix deg...
        FieldCoeffDet          matlab.ui.control.EditField       
        LabelNumericEditField4 matlab.ui.control.Label            % Nett Gain:
        FieldNettGain          matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField5 matlab.ui.control.Label            % Total S...
        FieldTotalSpent        matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField6 matlab.ui.control.Label            % Total G...
        FieldTotalGain         matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField7 matlab.ui.control.Label            % Profit ...
        FieldProfit            matlab.ui.control.NumericEditField % [-Inf Inf]
    end

    
    properties (Access = private)
        data % cell structure, consisting of: 1. x-points 2. y-points 3. polynomial coefficients 4. determination coefficient
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % ButtonOpen button pushed function
        function ButtonOpenButtonPushed(app)
            app.data = get_data_from_file(app.FieldFileName.Value,app.FieldDegree.Value,app.FieldLambda.Value,app.CheckMix.Value);
            app.DropDownYear.Enable = 'on';
            app.ButtonShowStats.Enable = 'on';
            app.DropDown510.Enable = 'on';
            app.FieldDailySpend.Enable = 'on';
            app.FieldCoeffDet.Enable = 'on';
            app.FieldNettGain.Enable = 'on';
            app.FieldTotalGain.Enable = 'on';
            app.FieldTotalSpent.Enable = 'on';
            app.FieldProfit.Enable = 'on';
            app.ButtonInvest.Enable = 'on';
            
            value = str2num(app.DropDownYear.Value) - 2006;
            strcell = strcat({'Year'},{' '},app.DropDownYear.Value);
            app.UIAxes.Title.String = strcell{1};
            strcell = strcat({'Coefficient of Determination:'},{'  '},num2str(app.data{value,4}*100,4),{'%'});
            app.FieldCoeffDet.Value = strcell{1};
            scatter(app.UIAxes,app.data{value,1},app.data{value,2});
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.data{value,1},polyval(app.data{value,3},app.data{value,1}));
            hold(app.UIAxes,'off');
        end

        % DropDownYear value changed function
        function DropDownYearValueChanged(app, event)
            value = str2num(app.DropDownYear.Value) - 2006;
            strcell = strcat({'Year'},{' '},app.DropDownYear.Value);
            app.UIAxes.Title.String = strcell{1};
            strcell = strcat({'Coefficient of Determination:'},{'  '},num2str(app.data{value,4}*100,4),{'%'});
            app.FieldCoeffDet.Value = strcell{1};
            scatter(app.UIAxes,app.data{value,1},app.data{value,2});
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.data{value,1},polyval(app.data{value,3},app.data{value,1}));
            hold(app.UIAxes,'off');
        end

        % ButtonShowStats button pushed function
        function ButtonShowStatsButtonPushed(app)
            x_all = [1:365];
            data_0 = get_data_from_file(app.FieldFileName.Value,10,0,0);
            [fd5_0,fd10_0] = poly_first_der_eval(data_0(:,3));
            data_05 = get_data_from_file(app.FieldFileName.Value,10,0.5,0);
            [fd5_05,fd10_05] = poly_first_der_eval(data_05(:,3));
            data_1 = get_data_from_file(app.FieldFileName.Value,10,1,0);
            [fd5_1,fd10_1] = poly_first_der_eval(data_1(:,3));
            
            figure('Name','10 Year Prediction Evaluation');
            subplot(3,1,1), scatter(app.data{10,1},app.data{10,2});
            title('10th year');
            subplot(3,1,2), plot(x_all,fd10_0,x_all,fd10_05,x_all,fd10_1);
            title('First derivative');
            legend('lambda = 0','lambda = 0.5', 'lambda = 1');
            subplot(3,1,3), plot(x_all(20:350),fd10_0(20:350),x_all(20:350),fd10_05(20:350),x_all(20:350),fd10_1(20:350));
            title('First derivative - 20:350');
            legend('lambda = 0','lambda = 0.5', 'lambda = 1');
            
            figure('Name','10 Year Prediction Evaluation')
            subplot(3,1,1), scatter(app.data{5,1},app.data{5,2});
            title('5th year');
            subplot(3,1,2), plot(x_all,fd5_0,x_all,fd5_05,x_all,fd5_1);
            title('First derivative');            
            legend('lambda = 0','lambda = 0.5', 'lambda = 1');
            subplot(3,1,3), plot(x_all(20:350),fd10_0(20:350),x_all(20:350),fd10_05(20:350),x_all(20:350),fd10_1(20:350));
            title('First derivative - 20:350');
            legend('lambda = 0','lambda = 0.5', 'lambda = 1');
        end

        % ButtonInvest button pushed function
        function ButtonInvestButtonPushed(app)
            [fd5,fd10] = poly_first_der_eval(app.data(:,3));
            if app.DropDown510.Value == '5'
                [tot_spent,tot_gained] = simulation_calc(app.data{5,1},app.data{5,2},fd5,app.FieldDailySpend.Value);
            else
                [tot_spent,tot_gained] = simulation_calc(app.data{10,1},app.data{10,2},fd10,app.FieldDailySpend.Value);
            end
            app.FieldNettGain.Value = tot_gained - tot_spent;
            app.FieldTotalSpent.Value = tot_spent;
            app.FieldTotalGain.Value = tot_gained;
            app.FieldProfit.Value = (tot_gained - tot_spent)/tot_spent*100;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'File Name:';
            app.FieldFileName = uieditfield(app.UIFigure, 'text');
            app.FieldFileName.Value = 'Stocks/aapl.us.txt';
            app.ButtonOpen = uibutton(app.UIFigure, 'push');
            app.ButtonOpen.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpenButtonPushed);
            app.ButtonOpen.Text = 'Open';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Year');
            xlabel(app.UIAxes, 'Day of year');
            ylabel(app.UIAxes, 'Stock value');
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Year';
            app.DropDownYear = uidropdown(app.UIFigure);
            app.DropDownYear.Items = {'2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016'};
            app.DropDownYear.ValueChangedFcn = createCallbackFcn(app, @DropDownYearValueChanged, true);
            app.DropDownYear.Enable = 'off';
            app.DropDownYear.Value = '2007';
            app.ButtonShowStats = uibutton(app.UIFigure, 'push');
            app.ButtonShowStats.ButtonPushedFcn = createCallbackFcn(app, @ButtonShowStatsButtonPushed);
            app.ButtonShowStats.Enable = 'off';
            app.ButtonShowStats.Text = 'Show Stats';
            app.ButtonInvest = uibutton(app.UIFigure, 'push');
            app.ButtonInvest.ButtonPushedFcn = createCallbackFcn(app, @ButtonInvestButtonPushed);
            app.ButtonInvest.Enable = 'off';
            app.ButtonInvest.Text = 'Investment Simulation';
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Investment year';
            app.DropDown510 = uidropdown(app.UIFigure);
            app.DropDown510.Items = {'5', '10'};
            app.DropDown510.Enable = 'off';
            app.DropDown510.Value = '5';
            app.PanelInvestmentStats = uipanel(app.UIFigure);
            app.PanelInvestmentStats.Title = 'Investment Stats';
            app.PanelInvestmentStats.Units = 'pixels';
            app.LabelNumericEditField4 = uilabel(app.PanelInvestmentStats);
            app.LabelNumericEditField4.Text = 'Nett Gain:';
            app.FieldNettGain = uieditfield(app.PanelInvestmentStats, 'numeric');
            app.FieldNettGain.ValueDisplayFormat = '%.2f';
            app.FieldNettGain.Enable = 'off';
            app.FieldNettGain.Editable = 'off';
            app.LabelNumericEditField5 = uilabel(app.PanelInvestmentStats);
            app.LabelNumericEditField5.Text = 'Total Spent:';
            app.FieldTotalSpent = uieditfield(app.PanelInvestmentStats, 'numeric');
            app.FieldTotalSpent.ValueDisplayFormat = '%.2f';
            app.FieldTotalSpent.Enable = 'off';
            app.FieldTotalSpent.Editable = 'off';
            app.LabelNumericEditField6 = uilabel(app.PanelInvestmentStats);
            app.LabelNumericEditField6.Text = 'Total Gain:';
            app.FieldTotalGain = uieditfield(app.PanelInvestmentStats, 'numeric');
            app.FieldTotalGain.ValueDisplayFormat = '%.2f';
            app.FieldTotalGain.Enable = 'off';
            app.FieldTotalGain.Editable = 'off';
            app.LabelNumericEditField7 = uilabel(app.PanelInvestmentStats);
            app.LabelNumericEditField7.Text = 'Profit [%]:';
            app.FieldProfit = uieditfield(app.PanelInvestmentStats, 'numeric');
            app.FieldProfit.ValueDisplayFormat = '%.2f';
            app.FieldProfit.Enable = 'off';
            app.FieldProfit.Editable = 'off';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Daily Spend';
            app.FieldDailySpend = uieditfield(app.UIFigure, 'numeric');
            app.FieldDailySpend.Limits = [1 10000];
            app.FieldDailySpend.Enable = 'off';
            app.FieldDailySpend.Value = 100;
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Polynomial Degree';
            app.FieldDegree = uieditfield(app.UIFigure, 'numeric');
            app.FieldDegree.Limits = [8 20];
            app.FieldDegree.Value = 10;
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'Ridge Lambda';
            app.FieldLambda = uieditfield(app.UIFigure, 'numeric');
            app.FieldLambda.Limits = [0 1];
            app.CheckMix = uicheckbox(app.UIFigure);
            app.CheckMix.Text = 'Mix degrees';
            app.FieldCoeffDet = uieditfield(app.UIFigure, 'text');
            app.FieldCoeffDet.Enable = 'off';
            app.FieldCoeffDet.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end